classdef BasicUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        OptionMenu              matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
        ProblemTypeButtonGroup  matlab.ui.container.ButtonGroup
        FeasibilityButton       matlab.ui.control.RadioButton
        LeastSquaresButton      matlab.ui.control.RadioButton
        CasefileDropDownLabel   matlab.ui.control.Label
        CasefileDropDown        matlab.ui.control.DropDown
        AlgorithmButtonGroup    matlab.ui.container.ButtonGroup
        ADMMButton              matlab.ui.control.RadioButton
        ALADINButton            matlab.ui.control.RadioButton
        GSKEditFieldLabel       matlab.ui.control.Label
        GSKEditField            matlab.ui.control.NumericEditField
        SolverButtonGroup       matlab.ui.container.ButtonGroup
        CasADiButton            matlab.ui.control.RadioButton
        fminconButton           matlab.ui.control.RadioButton
        fminuncButton           matlab.ui.control.RadioButton
        worhpButton             matlab.ui.control.RadioButton
        runButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)

            % casefile
            casefile = string(app.CasefileDropDown.Value);
            
            % generation shift key
            gsk = double(app.GSKEditField.Value);
            
            % problem type
            if app.FeasibilityButton.Value == true
                problem_type = 'feasibility';
            elseif app.LeastSquaresButton.Value == true
                problem_type = 'least-squares';
            end
            
            % algorithm
            if app.ADMMButton.Value == true
                algorithm = 'admm';
            elseif app.ALADINButton.Value == true
                algorithm = 'aladin';
            end
            
            % solver
            if app.CasADiButton.Value == true
                solver = 'Casadi+Ipopt';
            elseif app.fminconButton.Value == true
                solver = 'fmincon';
            elseif app.fminuncButton.Value == true
                solver = 'fminunc';
            elseif app.worhpButton.Value == true 
                solver = 'worhp';
            end
            
        test_file_UI(problem_type, casefile, gsk, algorithm, solver)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OptionMenu = uimenu(app.UIFigure);
            app.OptionMenu.Text = 'Option';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.ProblemTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ProblemTypeButtonGroup.Title = 'Problem Type';
            app.FeasibilityButton = uiradiobutton(app.ProblemTypeButtonGroup);
            app.FeasibilityButton.Text = 'Feasibility';
            app.FeasibilityButton.Value = true;
            app.LeastSquaresButton = uiradiobutton(app.ProblemTypeButtonGroup);
            app.LeastSquaresButton.Text = 'Least-Squares';
            app.CasefileDropDownLabel = uilabel(app.UIFigure);
            app.CasefileDropDownLabel.Text = 'Casefile';
            app.CasefileDropDown = uidropdown(app.UIFigure);
            app.CasefileDropDown.Items = {'14+30+9', '300+118', '118X3', '118X7', '118X8', '118X10', '1354X2', '1354X3', '1354X3+300X2'};
            app.CasefileDropDown.Value = '14+30+9';
            app.AlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.ADMMButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.ADMMButton.Text = 'ADMM';
            app.ADMMButton.Value = true;
            app.ALADINButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.ALADINButton.Text = 'ALADIN';
            app.GSKEditFieldLabel = uilabel(app.UIFigure);
            app.GSKEditFieldLabel.Text = 'GSK';
            app.GSKEditField = uieditfield(app.UIFigure, 'numeric');
            app.GSKEditField.Value = 1;
            app.SolverButtonGroup = uibuttongroup(app.UIFigure);
            app.SolverButtonGroup.Title = 'Solver';
            app.CasADiButton = uiradiobutton(app.SolverButtonGroup);
            app.CasADiButton.Text = 'CasADi';
            app.CasADiButton.Value = true;
            app.fminconButton = uiradiobutton(app.SolverButtonGroup);
            app.fminconButton.Text = 'fmincon';
            app.fminuncButton = uiradiobutton(app.SolverButtonGroup);
            app.fminuncButton.Text = 'fminunc';
            app.worhpButton = uiradiobutton(app.SolverButtonGroup);
            app.worhpButton.Text = 'worhp';
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasicUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end